classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CifradoCesarUIFigure          matlab.ui.Figure
        ArchivoMenu                   matlab.ui.container.Menu
        AbrirMenu                     matlab.ui.container.Menu
        IdiomasMenu                   matlab.ui.container.Menu
        AcercadeMenu                  matlab.ui.container.Menu
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        IdiomaDropDown                matlab.ui.control.DropDown
        IdiomaDropDownLabel           matlab.ui.control.Label
        GridLayout_2                  matlab.ui.container.GridLayout
        TextocifradoLabel             matlab.ui.control.Label
        TextoTextArea                 matlab.ui.control.TextArea
        GridLayout5                   matlab.ui.container.GridLayout
        LlavededesplazamientoSpinner  matlab.ui.control.Spinner
        LlavededesplazamientoSpinnerLabel  matlab.ui.control.Label
        GridLayout                    matlab.ui.container.GridLayout
        TextoplanoLabel               matlab.ui.control.Label
        TextoclaroTextArea            matlab.ui.control.TextArea
        GridLayout4                   matlab.ui.container.GridLayout
        DescifrarButton               matlab.ui.control.Button
        LimpiarButton                 matlab.ui.control.Button
        CifrarButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Help=helper %instancia un objeto de la clase Helper que esta en el archivo Helper.m
        fileReader;
        LanguagesManager=LanguagesManager;
        Language;
        cesar;
    end
    
    methods (Access = public)        
        function init(app)
            clc
            for i=1:size(app.IdiomaDropDown.Items,2)
                app.IdiomaDropDown.Items(1)=[];
            end
            for i=1:size(app.LanguagesManager.Languages,1)
                lang=app.LanguagesManager.Languages(i);
                app.IdiomaDropDown.Items=[app.IdiomaDropDown.Items,lang.Name];
            end
            app.Language=app.LanguagesManager.Languages(1);
            app.IdiomaDropDown.Value=app.Language.Name;
            app.cesar=CesarCipher(app.Language);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Menu selected function: AbrirMenu
        function AbrirMenuSelected(app, event)
            %Mostrar una ventana para seleccionar archivo filtrado .txt
            [file,path] = uigetfile('*.txt');
            %Si se selecciono un archivo
            if ~(isequal(file,0))
                %Obtener ruta completa del archivo seleccionado
                fullpath=fullfile(path,file);

                %instancia un objeto de la clase ProgressBar que esta en el
                %archivo ProgressBar.m
                pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
                %Mostrar barra de progeso
                pgrssbar.show('Leyendo archivo...');
                app.fileReader=FileReader(fullpath);
                data=app.fileReader.readFileAndFilter(app.Language);
                %Concatenar lineas en una string de texto y mostrarla en
                %el text area y limpiar el textarea 
                app.TextoTextArea.Value='';
                app.TextoclaroTextArea.Value=string(data);               
                pgrssbar.close();
            end            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
             %limpiar los textareas
             app.TextoclaroTextArea.Value=''; 
             app.TextoTextArea.Value='';
        end

        % Button pushed function: DescifrarButton
        function DescifrarButtonPushed(app, event)
            %Obtener el desplazamiento de la llave del control spinner
            keyOffset=app.LlavededesplazamientoSpinner.Value;
            
            %instancia un objeto de la clase ProgressBar que esta en el
            %archivo ProgressBar.m
            pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
            %Mostrar barra de progeso
            pgrssbar.show('Descifrando texto...');
            %asigna el valor del textarea decifrado al resultado de la función
            %'dechiper' que se encarga de decifrar el texto del textarea
            %cifrado y le envia el offset de la llave seleccionada
            app.TextoclaroTextArea.Value=app.cesar.decipher(app.TextoTextArea.Value,keyOffset);
            pgrssbar.close();
        end

        % Button pushed function: CifrarButton
        function CifrarButtonPushed(app, event)
           %Obtener el desplazamiento de la llave del control spinner
           keyOffset=app.LlavededesplazamientoSpinner.Value;
           
           %instancia un objeto de la clase ProgressBar que esta en el
           %archivo ProgressBar.m
           pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
           %Mostrar barra de progeso
           pgrssbar.show('Cifrando texto...');
           %asigna el valor del textarea cifrado al resultado de la función
           %'chiper' que se encarga de cifrar el texto del textarea
           %decifrado y le envia el offset de la llave seleccionada
           plainText=app.TextoclaroTextArea.Value;
           app.TextoTextArea.Value=app.cesar.cipher(plainText,keyOffset);
           pgrssbar.close();
        end

        % Menu selected function: AcercadeMenu
        function AcercadeMenuSelected(app, event)
            app.Help.centerWindow(about,app.CifradoCesarUIFigure);
        end

        % Menu selected function: IdiomasMenu
        function IdiomasMenuSelected(app, event)
            view=LanguagesView;   
            view.init(app.LanguagesManager,app);
        end

        % Value changed function: IdiomaDropDown
        function IdiomaDropDownValueChanged(app, event)
            value = app.IdiomaDropDown.Value;
            index=find(ismember(app.IdiomaDropDown.Items,value),1);
            app.Language=app.LanguagesManager.Languages(index);
            %instancia un objeto de la clase CesarChiper que esta en el
            %archivo CesarChiper.m
            app.cesar=CesarCipher(app.Language);
        end

        % Value changed function: LlavededesplazamientoSpinner
        function LlavededesplazamientoSpinnerValueChanged(app, event)
            value = app.LlavededesplazamientoSpinner.Value;
            if(value>=size(app.Language.Alphabet))
                msgbox("La llave debe ser menor que el tamaño del diccionario.","Error","error","modal");
                app.LlavededesplazamientoSpinner.Value=event.PreviousValue;
                return;
            end
            if(value<=0)
                msgbox("La llave debe ser mayor que cero.","Error","error","modal");
                app.LlavededesplazamientoSpinner.Value=event.PreviousValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CifradoCesarUIFigure = uifigure('Visible', 'off');
            app.CifradoCesarUIFigure.Name = 'Cifrado Cesar';
            app.ArchivoMenu = uimenu(app.CifradoCesarUIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.AbrirMenu = uimenu(app.ArchivoMenu);
            app.AbrirMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirMenuSelected, true);
            app.AbrirMenu.Text = 'Abrir';
            app.IdiomasMenu = uimenu(app.CifradoCesarUIFigure);
            app.IdiomasMenu.MenuSelectedFcn = createCallbackFcn(app, @IdiomasMenuSelected, true);
            app.IdiomasMenu.Text = 'Idiomas';
            app.AcercadeMenu = uimenu(app.CifradoCesarUIFigure);
            app.AcercadeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeMenuSelected, true);
            app.AcercadeMenu.Text = 'Acerca de...';
            app.GridLayout3 = uigridlayout(app.CifradoCesarUIFigure);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.CifrarButton = uibutton(app.GridLayout4, 'push');
            app.CifrarButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarButtonPushed, true);
            app.CifrarButton.Text = 'Cifrar';
            app.LimpiarButton = uibutton(app.GridLayout4, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.DescifrarButton = uibutton(app.GridLayout4, 'push');
            app.DescifrarButton.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButtonPushed, true);
            app.DescifrarButton.Text = 'Descifrar';
            app.GridLayout = uigridlayout(app.GridLayout3);
            app.TextoclaroTextArea = uitextarea(app.GridLayout);
            app.TextoplanoLabel = uilabel(app.GridLayout);
            app.TextoplanoLabel.Text = 'Texto plano';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.LlavededesplazamientoSpinnerLabel = uilabel(app.GridLayout5);
            app.LlavededesplazamientoSpinnerLabel.Text = 'Llave de desplazamiento:';
            app.LlavededesplazamientoSpinner = uispinner(app.GridLayout5);
            app.LlavededesplazamientoSpinner.ValueChangedFcn = createCallbackFcn(app, @LlavededesplazamientoSpinnerValueChanged, true);
            app.LlavededesplazamientoSpinner.Value = 1;
            app.GridLayout_2 = uigridlayout(app.GridLayout3);
            app.TextoTextArea = uitextarea(app.GridLayout_2);
            app.TextocifradoLabel = uilabel(app.GridLayout_2);
            app.TextocifradoLabel.Text = 'Texto cifrado';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.IdiomaDropDownLabel = uilabel(app.GridLayout6);
            app.IdiomaDropDownLabel.Text = 'Idioma:';
            app.IdiomaDropDown = uidropdown(app.GridLayout6);
            app.IdiomaDropDown.Items = {};
            app.IdiomaDropDown.ValueChangedFcn = createCallbackFcn(app, @IdiomaDropDownValueChanged, true);
            app.IdiomaDropDown.Value = {};
            % Show the figure after all components are created
            app.CifradoCesarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CifradoCesarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CifradoCesarUIFigure)
        end
    end
end